classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SearchListBoxLabel              matlab.ui.control.Label
        xButton                         matlab.ui.control.Button
        SearchListBox                   matlab.ui.control.ListBox
        EditFieldTextArea               matlab.ui.control.TextArea
        EmergencyStopButton             matlab.ui.control.Button
        startButton                     matlab.ui.control.Button
        flagacButton                    matlab.ui.control.Button
        SstartTestButton                matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        EditField_7                     matlab.ui.control.EditField
        EditField_6                     matlab.ui.control.EditField
        EditField_5                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        YollaButton                     matlab.ui.control.Button
        EditField_3                     matlab.ui.control.EditField
        readFromCOMButton               matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        EditField                       matlab.ui.control.EditField
        EnteraSerialPortNameButton      matlab.ui.control.Button
        serialportnameEditField         matlab.ui.control.EditField
        serialportnameexCOM3Label       matlab.ui.control.Label
        GRAPHICINFORMATIONPanel         matlab.ui.container.Panel
        CLEARButton                     matlab.ui.control.Button
        Image3_2                        matlab.ui.control.Image
        ForceNewtonNPanel               matlab.ui.container.Panel
        FREditField                     matlab.ui.control.EditField
        FRF3F1Label                     matlab.ui.control.Label
        F1EditField                     matlab.ui.control.EditField
        F1EditFieldLabel                matlab.ui.control.Label
        F2EditField                     matlab.ui.control.EditField
        F2EditFieldLabel                matlab.ui.control.Label
        F4EditField_2                   matlab.ui.control.EditField
        F4Label                         matlab.ui.control.Label
        F3EditField_2                   matlab.ui.control.EditField
        F3EditField_2Label              matlab.ui.control.Label
        GRAPHICPanel                    matlab.ui.container.Panel
        TARHEditField_2                 matlab.ui.control.EditField
        ResponsibleEditField            matlab.ui.control.EditField
        ResponsibleLabel                matlab.ui.control.Label
        RefNumberEditField              matlab.ui.control.EditField
        RefNumberLabel                  matlab.ui.control.Label
        TARHEditField                   matlab.ui.control.EditField
        DATEEditField                   matlab.ui.control.EditField
        SAATLabel                       matlab.ui.control.Label
        SAVEButton                      matlab.ui.control.Button
        PRINTButton                     matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        Image                           matlab.ui.control.Image
        DrawingPointsPanel              matlab.ui.container.Panel
        TestResultEditField             matlab.ui.control.EditField
        TestResultLabel                 matlab.ui.control.Label
        STRUTFEATURESPanel              matlab.ui.container.Panel
        CalculatedHighestForceEditField  matlab.ui.control.EditField
        CalculatedHighestForceLabel     matlab.ui.control.Label
        CalculatedlowestForceEditField  matlab.ui.control.EditField
        CalculatedlowestForceLabel      matlab.ui.control.Label
        DownToleranceEditField          matlab.ui.control.EditField
        DownToleranceLabel              matlab.ui.control.Label
        UpToleranceEditField            matlab.ui.control.EditField
        UpToleranceLabel                matlab.ui.control.Label
        StrokeMmEditField               matlab.ui.control.EditField
        StrokeMmLabel                   matlab.ui.control.Label
        DesiredNewtonNEditField         matlab.ui.control.EditField
        DesiredNewtonNEditFieldLabel    matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        importEditField                 matlab.ui.control.EditField
        IMPORTButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
         % Description
         IP %input
         OP %output
         sayachelp=0;
         sayaccatchSerial=0;
        seriport
        portname
        str
       sayacstringsofHex=1;
       stringArrayN = {};
       stringArrayMm= {};
       stopFlag = false;
       sayacforX5=1;
       sayacforXsonueksi5=1;
          strylar =[];
          strylar1 =[];
          sayacs=0;
          sayac1s=0;
          x111={};
          y111={};
          x=[];
          y=[];
          x5index=0;

        Property11 % Description
    end
    
    properties (Access = public)
        Property10 % Description


    end
   
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        set(app.PRINTButton, 'Visible', 'off');
                        set(app.Image2, 'Visible', 'off');

        end

        % Button pushed function: IMPORTButton
        function IMPORTButtonPushed(app, event)
            clc; 
            [filename, filepath] = uigetfile('*.txt', 'Select a text file');
            
            app.importEditField.Value = strcat(filepath,filename);
    %          % Check if the user canceled the file selection
    if isequal(filename, 0)
        return; % Exit the function if no file was selected
    end
    % 
      % Read the data from the selected text file using importdata
    fullpath = fullfile(filepath, filename);
    data = importdata(fullpath);
    

            % data = importdata(filename);

          
               % احتسب عدد الأعمدة
            numColumns = size(data,2);

% انشئ مصفوفتين فارغتين لتخزين الأعمدة
            x = [];
            y = [];
            y3 = [];
            y4 = [];
            y5 = [];
            y6 = [];
            y7 = [];
            y8 = [];
            y9= [];
            strylar =[];
            strylar1 =[];

            % افصل البيانات إلى الأعمدة
for i = 1:numColumns
    columnData = data(:, i);
    if i == 1
        y = columnData;
    elseif i==2
        x = columnData;
   elseif i==3
          y3 = columnData;  
       elseif i==4
                     y4 = columnData;
           elseif i==5
                         y5 = columnData;
               elseif i==6
                             y6 = columnData;
                   elseif i==7
                                 y7 = columnData;
                       elseif i==8
                                     y8 = columnData;
                           elseif i==9
                                         y9 = columnData;
    end
end

    try
app.DesiredNewtonNEditField.Value=string(y3(1));
app.StrokeMmEditField.Value=string(y4(1));
app.UpToleranceEditField.Value=string(y5(1));
app.DownToleranceEditField.Value=string(y6(1));
app.CalculatedlowestForceEditField.Value=string(y7(1));
app.CalculatedHighestForceEditField.Value=string(y8(1));


    catch
        % Display an error message if there was an issue with printing
        errordlg('There is missing data in the entered text file!!! Please fix it and try again.', 'Error', 'modal');
    end


xsonueksi5=max(x)-5;
x5=5;
yMax=max(y);


app.UITable.Data =[data(:,1),data(:,2)];


plot(app.UIAxes,x,y,'-');
grid(app.UIAxes);

hold(app.UIAxes);
plot(app.UIAxes,[x5, x5], [0, yMax], 'r', 'LineWidth', 1);
plot(app.UIAxes,[xsonueksi5, xsonueksi5], [0, yMax], 'r', 'LineWidth', 1);
xlabel(app.UIAxes,'Distance (Mm)', 'FontSize', 12,'Color',"b");
ylabel(app.UIAxes,'Force (Newton (N))', 'FontSize', 12, 'Color',"b");


% legend(app.UIAxes,'F3','F4','F2','F1');
% Add a legend to the plot with custom colors
% legend(app.UIAxes,'Location', 'Best', 'FontSize', 12);
% 
% % Set custom colors for the legend
% legend(app.UIAxes, 'TextColor', 'red')

d = datetime("today");
td = datetime('now', 'Format', 'HH:mm:ss');
myTitle = "generated on : " + string(d)+'  '+ string(td);
title(app.UIAxes,myTitle);

currentDate = datetime('now', 'Format', 'dd/MM/yyyy  HH:mm');


app.DATEEditField.Value = string(currentDate);


sayac=0;
sayac1=0;

for n=1:length(x)
if x(n)==5


    sayac=sayac+1;
    strylar(sayac)=string(y(n));

end

if x(n)==xsonueksi5

    sayac1=sayac1+1;
    strylar1(sayac1)=string(y(n));

end

end


    try

if y9(1)==1
app.TestResultEditField.FontColor=[0, 1, 0];
app.TestResultEditField.Value=string(strylar(2))+' N (Newton) , The Result Is OK..';
else
    app.TestResultEditField.FontColor=[1, 0, 0];
app.TestResultEditField.Value=string(strylar(2))+' N (Newton) , Out Of Tolerance !';
end
     
    catch
        % Display an error message if there was an issue with printing
        errordlg('There is missing data in the entered text file!!! Please fix it and try again.', 'Error', 'modal');
    end


plot(app.UIAxes,x5, strylar(1), 'bo', 'MarkerSize', 10); % Adjust the size and color as needed
plot(app.UIAxes,xsonueksi5, strylar1(1), 'bo', 'MarkerSize', 10); % Adjust the size and color as needed
plot(app.UIAxes,xsonueksi5, strylar1(2), 'bo', 'MarkerSize', 10); % Adjust the size and color as needed
plot(app.UIAxes,x5, strylar(2),'bo', 'MarkerSize', 10); % Adjust the size and color as needed
% legend(app.UIAxes,'','','','F3','F4','F2','F1','Location', 'Best', 'FontSize', 12);


% Points where you want to display information boxes
points_x = [x5+((1/15)*max(x)), xsonueksi5-((1/10)*max(x)),xsonueksi5-((2/20)*max(x)),x5+((1/15)*max(x))];
points_y = [strylar(1)+((2/25)*max(y)), strylar1(1)+((1/20)*max(y)),strylar1(2)-((2/20)*max(y)),strylar(2)-((2/20)*max(y))];
info_text = {'F3:'+string(strylar(1)), 'F4:'+string(strylar1(1)), 'F2:'+string(strylar1(2)), 'F1:'+string(strylar(2))};

% Loop through the points and add information boxes
for i = 1:length(points_x)
    x_pos = points_x(i);
    y_pos = points_y(i);
    text(app.UIAxes,x_pos, y_pos, info_text{i}, 'HorizontalAlignment', 'center', 'BackgroundColor', 'white', 'EdgeColor', 'black');
end

% Customize the appearance of the information boxes as needed


app.F3EditField_2.Value = string(strylar(1));
app.F4EditField_2.Value = string(strylar1(1));

app.F2EditField.Value = string(strylar1(2));
app.F1EditField.Value = string(strylar(2));

app.FREditField.Value = string(strylar(1)-strylar(2));


            % [filename,pathname] = uigetfile({'*.xls'},'File Selector');
            % 
            % app.importEditField.Value = strcat(pathname,filename);
            % 
            % 
            % 
            % app.IP=xlsread(app.importEditField.Value,'x');
            % app.OP= xlsread(app.importEditField.Value,'y');
            % 
            % 
            % 
            % 
            % 
            % plot(app.UIAxes,app.IP,app.OP,"-*");
            % 
            % 
            % 
            % 
            % app.grafklengthEditField.Value = length(app.OP);


        end

        % Callback function
        function SaveMenuSelected(app, event)
            % 
            % [file,path]= uiputfile("result.xls");
            % xlswrite(strcat(path,file),app.OP,"Output");
            
        end

        % Callback function
        function PushTool7Clicked(app, event)
    %          printerName = 'grafik'; % Replace with your printer's name
    % printOptions = '-dps';
    % 
    % % Generate a temporary file name for printing
    % tempFilename = tempname;
    % 
    % % Print the figure
    % print(app.UIFigure, tempFilename, printOptions);
    % 
    % % Print the file using the system command
    % printCommand = ['print -printer "' printerName '" ' tempFilename];
    % system(printCommand);
    % 
    % % Delete the temporary file
    % delete(tempFilename);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: PRINTButton
        function PRINTButtonPushed(app, event)

            % Prompt the user to select a PDF file to print
    [pdfFileName, pdfPath] = uigetfile('*.pdf', 'Select a PDF File to Print');
    
    % Check if the user canceled the file dialog
    if isequal(pdfFileName, 0)
        return;
    end
    
    % Combine the path and file name to get the full file path
    pdfFilePath = fullfile(pdfPath, pdfFileName);
    
    % Check if the selected file exists
    if ~exist(pdfFilePath, 'file')
        errordlg('The selected PDF file does not exist.', 'Error', 'modal');
        return;
    end
    
    try
        % Use the system's default printer to print the selected PDF file
        system(['print /C:"' getdefaultprinter() '" "' pdfFilePath '"']);
        
        % Display a message indicating that the document is being sent to the printer
        msgbox(['Printing the document: ' pdfFileName], 'Printing', 'modal');
    catch
        % Display an error message if there was an issue with printing
        errordlg('Failed to print the PDF document.', 'Error', 'modal');
    end

        end

        % Value changing function: DATEEditField
        function DATEEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)

        f=app.UIFigure;
        filter = {'*.pdf';'*.png';'*.jpg';'*.tif'};
        [filename,filepath] = uiputfile(filter);
        if ischar(filename)
                % Set the panel's visibility back to 'hidden'
            set(app.SAVEButton, 'Visible', 'off');
            set(app.Image3, 'Visible', 'off');
            set(app.importEditField, 'Visible', 'off');
            set(app.IMPORTButton, 'Visible', 'off');
            exportapp(f,[filepath filename]);
            app.IP  = strcat(filepath,filename);
        end
   

            set(app.SAVEButton, 'Visible', 'on');
            set(app.Image3, 'Visible', 'on');
            set(app.importEditField, 'Visible', 'on');
            set(app.IMPORTButton, 'Visible', 'on');
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            
            if app.sayachelp == 0
            app.SearchListBox.Visible = 'on';
            app.xButton.Visible = 'on';

                      serialerornocale= serialportlist("all");
                      if isempty(serialerornocale)==1
                         
                          items = {'No COM Founded'};
                   
                      app.SearchListBox.Items = items;
                      
                      else
                      app.SearchListBox.Items = serialerornocale;    
                     % serialerornocale= sprintf();
                      end

                app.sayachelp=app.sayachelp+1;
            else
            app.SearchListBox.Visible = 'off';
            app.xButton.Visible = 'off';
            app.sayachelp=0;
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            % Clear data from UIAxes1
            cla(app.UIAxes);
            
            
        end

        % Button pushed function: EnteraSerialPortNameButton
        function EnteraSerialPortNameButtonPushed(app, event)
            % Connect tuşu 
            %Stop Func From Emergency button
            % if app.stopFlag   % assuming app.stopFlag=true means process should abort
            %  return
            %  end

            app.stopFlag=false;

            app.portname = app.serialportnameEditField.Value;

            try

             if isempty(app.seriport)==1


              app.seriport = serialport(app.portname, 9600,"Parity","even","DataBits",7,"StopBits",1,'Timeout',5);
              configureTerminator(app.seriport,"CR/LF");
              % app.EditFieldTextArea.FontColor=[0, 1, 0];
              % app.EditFieldTextArea.Value = 'Serial port connected successfully.';
              setDTR(app.seriport ,false);


             StartTestButtonPushed(app,event)


                % SstartTestButtonPushed2(app, event)

             else

              app.seriport =0;

              app.seriport = serialport(app.portname, 9600,"Parity","even","DataBits",7,"StopBits",1,'Timeout',5);
              configureTerminator(app.seriport,"CR/LF");
              % app.EditFieldTextArea.FontColor=[0, 1, 0];
              % app.EditFieldTextArea.Value = 'Serial port connected successfully.';
              setDTR(app.seriport ,false);

              StartTestButtonPushed(app,event)

              end
            
            catch 
                    app.EditFieldTextArea.FontColor=[1, 0, 0];
                   % app.EditFieldTextArea.Value = 'Failed to Connect with the serial port(COM):  '+string(app.portname);
                   if app.sayaccatchSerial>=0 && app.sayaccatchSerial<=1
                     erornocale= sprintf('Failed to Connect with \nthe serial port(COM): '+string(app.portname)+' \nplease fix it and try again');
                     app.EditFieldTextArea.Value = erornocale;

                     app.sayaccatchSerial=app.sayaccatchSerial+1;

                   elseif app.sayaccatchSerial>=2 

                     erornocale= sprintf('Failed to Connect with \nthe serial port(COM): '+string(app.portname)+' \nplease fix it and try again');
                     app.EditFieldTextArea.Value = erornocale;
                     serialerornocale= serialportlist("all");


                      if isempty(serialerornocale)==1

                      app.EditFieldTextArea.FontColor=[0, 0, 1];
                      erornocale = sprintf('There is no Cable connected !\nplease plug-in the Cable and try again.');
                      app.EditFieldTextArea.Value = erornocale;
                      else
                      app.SearchListBox.Items = serialerornocale;    
                     % serialerornocale= sprintf();
                      app.SearchListBox.Visible ="on";
                      app.xButton.Visible="on";
                      end


                    app.sayaccatchSerial=0;
                    
                   end
               
            end


        end

        % Callback function
        function StartTestButtonPushed(app, event)

                        % %Stop Func From Emergency button
                        % if app.stopFlag   % assuming app.stopFlag=true means process should abort
                        % 
                        %  return
                        % end


                try
                
        
                    %flag yada ayrağı kaldır
                    % flagacButtonPushed(app, event)
                      % writeline(app.seriport,":11050840FF00A3");

                       % cihaz hazir mi degil mi 
                     writeline(app.seriport,":110108400001A5");

                    % Display the received data in a UI component (e.g., a Label)
                     app.EditFieldTextArea.FontColor=[0.00, 0.00, 0.00];
                     app.EditFieldTextArea.Value = '...please wait';
                    
                     receivedData01 = readline(app.seriport);
                    
                     ss =[];
                    
                     app.EditField_3.Value = '';
                     % Display the received data in a UI component (e.g., a Label)
                     app.EditField_3.Value =receivedData01;
                     ss = app.EditField_3.Value ;
                     
                
                     eror0or1= str2double(ss(9))


                     if eror0or1== 1
                app.EditFieldTextArea.Value = '';
                app.EditFieldTextArea.FontColor=[0, 1, 0];
                app.EditFieldTextArea.Value = 'Connected Successfully';

                app.SstartTestButton.Enable = "on";


                     else
                 app.EditFieldTextArea.Value = '';
                 app.EditFieldTextArea.FontColor=[0, 0, 1];
                 app.EditFieldTextArea.Value = 'Cheking.. Pleaase Wait';
             
                 app.SstartTestButton.Enable = "off";
                 pause(2)
                 flagacButtonPushed(app, event)
                     end


                    % Display the received data in a UI component (e.g., a Label)
                

                catch
  % hatayı yakala eğer USB port mı koptu yoksa kaBlunun diğer ucu makineye
  % mi takmadık
                    ss = sprintf("Check From COM port OR Write to Paper Button is Pressed !");
                    app.EditFieldTextArea.FontColor=[0, 0, 1];
                    app.EditFieldTextArea.Value =  ss;
                    disp('error in connect or unconnected code 0 or 1');

                end
        end

        % Button pushed function: SstartTestButton
        function SstartTestButtonPushed2(app, event)
            
                        %                 %Stop Func From Emergency button
                        % if app.stopFlag   % assuming app.stopFlag=true means process should abort
                        %  return
                        % end

               app.stopFlag = false;
               % Clear data from UIAxes1
               cla(app.UIAxes);
               app.UIAxes.reset
                
               app.UITable.Data =[];
               app.sayacstringsofHex = 1 ;
               app.stringArrayMm={};
               app.stringArrayN ={};
               app.sayacforX5=1;
               app.sayacforXsonueksi5=1;
               app.strylar =[];
               app.strylar1 =[];
               app.sayacs=0;
               app.sayac1s=0;
               app.x111={};
               app.y111={};
               app.x=[];
               app.y=[];
              % writeline(app.seriport,":1105083BFF00A8"); ??

                app.SstartTestButton.Enable ="off";
                % amortisor testi çalıştır
              writeline(app.seriport,":11050841FF00A2");
                app.EditField_3.Value = '';
              YollaButtonPushed(app, event);
              % readFromCOMButtonPushed(app, event)

        end

        % Button pushed function: YollaButton
        function YollaButtonPushed(app, event)

                        %Stop Func From Emergency button
                        if app.stopFlag   % assuming app.stopFlag=true means process should abort
                         return
                        end

              try
                %Testin Değerlerini sensörden oku
              writeline(app.seriport,":110310020002D8");
              % disp("tamam")
              pause(0.01);
              

            catch

                app.EditField.Value = 'Failed to Write DATA to '+string(app.portname)+' please  try again .';
                app.EditField.FontColor=[0, 0, 1];


              end

              try
                  readFromCOMButtonPushed(app, event);
              catch 
                  disp("error");
              end


        end

        % Button pushed function: readFromCOMButton
        function readFromCOMButtonPushed(app, event)
                   disp('1')
                        %Stop Func From Emergency button
                        if app.stopFlag   % assuming app.stopFlag=true means process should abort
                         return
                        end
disp('2')

                        % Read data from the COM port (e.g., read one line of text)

app.str=[];


% configureCallback(app.seriport,"terminator",@EditField_3ValueChanging)

receivedData = readline(app.seriport);
  disp('3')
app.EditField_3.Value = '';
% Display the received data in a UI component (e.g., a Label)
app.EditField_3.Value = string(receivedData);

% app.seriport.Terminator;
if app.seriport.Terminator == "CR/LF"
    app.str = app.EditField_3.Value ;


       disp('4')
    
    app.EditField_4.Value = string(hex2dec(app.str(8:11))) ;
    app.EditField_5.Value = string(hex2dec(app.str(12:15))) ;
    app.EditField_6.Value = string(hex2dec(app.str(16:length(app.str)))) ;
    % app.EditField_6.Value = string(hex2dec(app.str(14:15))) ;
    % app.EditField_7.Value = string(hex2dec(app.str(18:length(app.str)))) ;
   
disp('5')

    if app.sayacstringsofHex == 1
    app.stringArrayN{app.sayacstringsofHex} = 0 ;
    app.stringArrayMm{app.sayacstringsofHex} = 0 ;
    
    else
        disp('6')

          app.stringArrayN{app.sayacstringsofHex} = hex2dec(app.str(8:11)) ;
          app.stringArrayMm{app.sayacstringsofHex} = hex2dec(app.str(12:15)) ;
          % plot(app.UIAxes,x,y,'-');
          % grid(app.UIAxes);
          % hold(app.UIAxes);
% disp('7')
          if app.stringArrayMm{app.sayacstringsofHex} ==0
          
                           % cihaz hazir mi degil mi 
                     writeline(app.seriport,":110108400001A5");
% disp('8')
                    % Display the received data in a UI component (e.g., a Label)
                    
                     receivedData01 = readline(app.seriport);
             % disp('9')       
                     ss1 =[];
                    
                     app.EditField.Value = '';
                     % Display the received data in a UI component (e.g., a Label)
                     app.EditField.Value =receivedData01;
                     ss1 = app.EditField.Value ;
        % disp('10')  
                     eror0or12= str2double(ss1(9))
                               % disp('11')  

                     if eror0or12==0
                         
                         
                    StopButtonPushed(app,event)


                     end
        disp('12')  
          end
                     
    end
    

        disp('13')  


    app.sayacstringsofHex = app.sayacstringsofHex+1 ;
        disp('14')  
end
                 

                 % app.EditField_3.Value = '';

               %TODO
                 YollaButtonPushed(app,event);


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

                 app.stopFlag=true;


         app.x111= app.stringArrayMm(:)';

         app.y111  = app.stringArrayN(:)';

         xn= cell2mat(cellfun(@double,app.x111,'UniformOutput',false));
         yn= cell2mat(cellfun(@double,app.y111,'UniformOutput',false));
  
         % app.x= xn;
         % app.y = yn;


         sayaccccc=1;

            for r=1:length(xn)
                if r <=length(xn)-1
            % next = xn(r+1);
            % nowValue = xn(r);

                if xn(r) == 0 && sayaccccc==1
                    app.x(sayaccccc)= xn(r);
                    app.y(sayaccccc)= yn(r);
                    sayaccccc=sayaccccc+1;

                elseif  xn(r) < xn(r+1)
                    app.x(sayaccccc)= xn(r);
                    app.y(sayaccccc)= yn(r);
                    sayaccccc=sayaccccc+1; 

                elseif xn(r) > xn(r+1)
                    app.x(sayaccccc)= xn(r);
                    app.y(sayaccccc)= yn(r);
                    sayaccccc=sayaccccc+1;


                end

            %     if xn(r) > xn(r+1)
            %         app.x(sayaccccc)= xn(r);
            %         app.y(sayaccccc)= yn(r);
            %         sayaccccc=sayaccccc+1;
            %     end
            % 
            % if xn(r) == xn(r-1)
            %   app.x(r) = xn(r+1);
            % end
                elseif r ==length(xn)

                    app.x(sayaccccc)= xn(r);
                    app.y(sayaccccc)= yn(r);

                end

            end


         x5=5;
         yMax=max(app.y);
         xsonueksi5=max(app.x)-5;
     
        
        for i=1:length(app.x)
            %ilk x5 ve y1(indexX5)    F3
            if app.x(i)==6 && app.sayacforX5==1
           % x5=x(i-1);
           app.x5index=i-1;
           app.strylar(app.sayacforX5)=string(app.y(app.x5index));
           app.sayacforX5=app.sayacforX5+1;
            end
          %ilk x5 ve y2(indexX5)    F1
            if app.x(i)==4  && app.sayacforX5==2
           app.strylar(app.sayacforX5)=string(app.y(i-1));
           app.sayacforX5=app.sayacforX5+1;
            end

            % xsonu-5 ve y1(xsonu-5)    F4
            if app.x(i)==xsonueksi5+1 && app.sayacforXsonueksi5==1
           app.strylar1(app.sayacforXsonueksi5)=string(app.y(i-1));
           app.sayacforXsonueksi5=app.sayacforXsonueksi5+1;
            end

            % xsonu-5 ve y2(xsonu-5)    F2
            if app.x(i)==xsonueksi5-1 && app.sayacforXsonueksi5==2
           app.strylar1(app.sayacforXsonueksi5)=string(app.y(i-1));
           app.sayacforXsonueksi5=app.sayacforXsonueksi5+1;
            end


        end

       % app.x(app.x5index)


    %       for n=1:length(x)
    %  if x(n)==5
    %     sayacs=sayacs+1;
    %     strylar(sayacs)=string(y(n));
    %  end
    %  if x(n)==xsonueksi5
    %     sayac1s=sayac1s+1;
    %     strylar1(sayac1s)=string(y(n));
    %  end
    % 
    % end


       app.UITable.Data =[app.y',app.x'];


        plot(app.UIAxes,app.x,app.y,'-');
        disp('i')
        grid(app.UIAxes);
        hold(app.UIAxes);
        plot(app.UIAxes,[x5, x5], [0, yMax], 'r', 'LineWidth', 1);
        plot(app.UIAxes,[xsonueksi5, xsonueksi5], [0, yMax], 'r', 'LineWidth', 1);
        xlabel(app.UIAxes,'Distance (Mm)', 'FontSize', 12,'Color',"b");
        ylabel(app.UIAxes,'Force (Newton (N))', 'FontSize', 12, 'Color',"b");

        d = datetime("today");
        td = datetime('now', 'Format', 'HH:mm:ss');
        myTitle = "generated on : " + string(d)+'  '+ string(td);
        title(app.UIAxes,myTitle);
        currentDate = datetime('now', 'Format', 'dd/MM/yyyy  HH:mm');
        app.DATEEditField.Value = string(currentDate);


 % 

            plot(app.UIAxes,x5, app.strylar(1), 'go', 'MarkerSize', 8); % Adjust the size and color as needed
            plot(app.UIAxes,xsonueksi5, app.strylar1(1), 'go', 'MarkerSize', 8); % Adjust the size and color as needed
            plot(app.UIAxes,xsonueksi5, app.strylar1(2), 'go', 'MarkerSize', 8); % Adjust the size and color as needed
            plot(app.UIAxes,x5, app.strylar(2),'go', 'MarkerSize', 8); % Adjust the size and color as needed
            % legend(app.UIAxes,'','','','F3','F4','F2','F1','Location', 'Best', 'FontSize', 12);

 
            % Points where you want to display information boxes
            points_x = [x5+((1/15)*max(app.x)), xsonueksi5-((1/10)*max(app.x)),xsonueksi5-((2/20)*max(app.x)),x5+((1/15)*max(app.x))];
            points_y = [app.strylar(1)+((2/25)*max(app.y)), app.strylar1(1)+((1/20)*max(app.y)),app.strylar1(2)-((2/15)*max(app.y)),app.strylar(2)-((2/20)*max(app.y))];
            info_text = {'F3:'+string(app.strylar(1)), 'F4:'+string(app.strylar1(1)), 'F2:'+string(app.strylar1(2)), 'F1:'+string(app.strylar(2))};

            % Loop through the points and add information boxes
            for i = 1:length(points_x)
                x_pos = points_x(i);
                y_pos = points_y(i);
                text(app.UIAxes,x_pos, y_pos, info_text{i}, 'HorizontalAlignment', 'center', 'BackgroundColor', 'white', 'EdgeColor', 'black');
            end

            
            % Customize the appearance of the information boxes as needed
            
            app.F3EditField_2.Value = string(app.strylar(1));
            app.F4EditField_2.Value = string(app.strylar1(1));

            app.F2EditField.Value = string(app.strylar1(2));
            app.F1EditField.Value = string(app.strylar(2));

            app.FREditField.Value = string(app.strylar(1)-app.strylar(2));


          try


   % cihaz hazir mi degil mi 
                     writeline(app.seriport,":110108400001A5");

                    % Display the received data in a UI component (e.g., a Label)

                    
                     receivedData011 = readline(app.seriport);
                    
                     ssss =[];
                    
                     app.EditField_3.Value = '';
                     % Display the received data in a UI component (e.g., a Label)
                     app.EditField_3.Value =receivedData011;
                     ssss = app.EditField_3.Value ;
                     
                
                     eror0or11= str2double(ssss(9))


                     if eror0or11 == 1
                % app.EditFieldTextArea.Value = '';
                % app.EditFieldTextArea.FontColor=[0, 1, 0];
                % app.EditFieldTextArea.Value = 'Connected Successfully';

                % app.SstartTestButton.Enable = "on";
                 
                % ac Falg
                app.SstartTestButton.Enable = 'on';
                receivedData01 = readline(app.seriport);   
                app.EditField.Value = '';
                % Display the received data in a UI component (e.g., a Label)
                app.EditField.Value =string(receivedData01);


                     else
                writeline(app.seriport,":11050840FF00A3");
                app.SstartTestButton.Enable = 'on';
                receivedData011 = readline(app.seriport);   
                app.EditField_3.Value = '';
                % Display the received data in a UI component (e.g., a Label)
                app.EditField_3.Value =receivedData011;
                %  app.EditFieldTextArea.Value = '';
                %  app.EditFieldTextArea.FontColor=[0, 0, 1];
                %  app.EditFieldTextArea.Value = 'The Machine Is Not Ready!';
                % app.SstartTestButton.Enable = "off";


                     end
          catch

                disp('error in the stop func')
          end


        end

        % Value changing function: EditField_3
        function EditField_3ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: flagacButton
        function flagacButtonPushed(app, event)

            try
                writeline(app.seriport,":11050840FF00A3");
                    receivedData = readline(app.seriport);

  
                    app.EditField_3.Value = '';
                    % Display the received data in a UI component (e.g., a Label)
                    app.EditField_3.Value = string(receivedData);

                    StartTestButtonPushed(app, event);
            catch
                errordlg('The First Please Connect with COM port.', 'Error', 'modal');

                disp("error in flagacButton")
            end


        end

        % Callback function
        function XButtonPushed(app, event)
            % app.EditField_2.Visible ="off";
            % app.XButton.Visible ="off";
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)

        writeline(app.seriport,":11050841FF00A2");
        receivedData = readline(app.seriport);
        
          
        app.EditField_3.Value = '';
        % Display the received data in a UI component (e.g., a Label)
        app.EditField_3.Value = string(receivedData);
        
        YollaButtonPushed(app, event)


        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)


              if strcmp(app.SstartTestButton.Enable, 'on')
                 writeline(app.seriport,":11050879FF006A");
              else
                 writeline(app.seriport,":11050879FF006A");
                 pause(0.1)
                 writeline(app.seriport,":11050879FF006A");

              end

             
              app.EditField.Value = '';
              app.F1EditField.Value = '';
              app.F2EditField.Value = '';
              app.F3EditField_2.Value = '';
              app.F4EditField_2.Value = '';
              app.FREditField.Value = '';
              app.EditField_3.Value = '';
              app.EditField_4.Value = '';
              app.EditField_5.Value = '';
              app.EditField_6.Value = '';
              app.EditField_7.Value = '';
              app.TestResultEditField.Value = '';

              app.SstartTestButton.Enable = "off";
              app.UITable.Data =[];

              app.stopFlag=true;

              app.sayacstringsofHex = 1 ;

              % StopButtonPushed(app, event)

        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

            app.SearchListBox.Visible = 'off';
            app.xButton.Visible = 'off';
             app.sayachelp=0;
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            app.SearchListBox.Visible = 'off';
            app.xButton.Visible = 'off';
            app.sayachelp=0;

            % serial portu Boşalt
            % app.seriport = 0;
            % app.seriport

        end

        % Double-clicked callback: SearchListBox
        function SearchListBoxDoubleClicked(app, event)

            item = event.InteractionInformation.Item;  
            sss= app.SearchListBox.Items;
            stringArray = cellstr(sss);
            app.serialportnameEditField.Value =string(stringArray(item)) ;
            EnteraSerialPortNameButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.IMPORTButton = uibutton(app.UIFigure, 'push');
            app.IMPORTButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.IMPORTButton.Enable = 'off';
            app.IMPORTButton.Visible = 'off';
            app.IMPORTButton.Text = 'IMPORT';
            app.importEditField = uieditfield(app.UIFigure, 'text');
            app.importEditField.Visible = 'off';
            app.DrawingPointsPanel = uipanel(app.UIFigure);
            app.DrawingPointsPanel.Title = 'Drawing Points';
            app.UITable = uitable(app.DrawingPointsPanel);
            app.UITable.ColumnName = {'Force Newton (N)'; 'Distance (Mm)'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.STRUTFEATURESPanel = uipanel(app.DrawingPointsPanel);
            app.STRUTFEATURESPanel.Title = 'STRUT FEATURES';
            app.DesiredNewtonNEditFieldLabel = uilabel(app.STRUTFEATURESPanel);
            app.DesiredNewtonNEditFieldLabel.Text = 'Desired Newton (N)';
            app.DesiredNewtonNEditField = uieditfield(app.STRUTFEATURESPanel, 'text');
            app.StrokeMmLabel = uilabel(app.STRUTFEATURESPanel);
            app.StrokeMmLabel.Text = 'Stroke (Mm):';
            app.StrokeMmEditField = uieditfield(app.STRUTFEATURESPanel, 'text');
            app.UpToleranceLabel = uilabel(app.STRUTFEATURESPanel);
            app.UpToleranceLabel.Text = 'Up Tolerance(%):';
            app.UpToleranceEditField = uieditfield(app.STRUTFEATURESPanel, 'text');
            app.DownToleranceLabel = uilabel(app.STRUTFEATURESPanel);
            app.DownToleranceLabel.Text = 'Down Tolerance(%):';
            app.DownToleranceEditField = uieditfield(app.STRUTFEATURESPanel, 'text');
            app.CalculatedlowestForceLabel = uilabel(app.STRUTFEATURESPanel);
            app.CalculatedlowestForceLabel.Text = 'Calculated lowest Force:';
            app.CalculatedlowestForceEditField = uieditfield(app.STRUTFEATURESPanel, 'text');
            app.CalculatedHighestForceLabel = uilabel(app.STRUTFEATURESPanel);
            app.CalculatedHighestForceLabel.Text = 'Calculated Highest Force:';
            app.CalculatedHighestForceEditField = uieditfield(app.STRUTFEATURESPanel, 'text');
            app.TestResultLabel = uilabel(app.DrawingPointsPanel);
            app.TestResultLabel.Text = 'Test Result:';
            app.TestResultEditField = uieditfield(app.DrawingPointsPanel, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'faparkes.png');
            app.GRAPHICINFORMATIONPanel = uipanel(app.UIFigure);
            app.GRAPHICINFORMATIONPanel.Title = 'GRAPHIC INFORMATION';
            app.UIAxes = uiaxes(app.GRAPHICINFORMATIONPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image2 = uiimage(app.GRAPHICINFORMATIONPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '3022251.png');
            app.Image3 = uiimage(app.GRAPHICINFORMATIONPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'save_icon_125167.png');
            app.PRINTButton = uibutton(app.GRAPHICINFORMATIONPanel, 'push');
            app.PRINTButton.ButtonPushedFcn = createCallbackFcn(app, @PRINTButtonPushed, true);
            app.PRINTButton.Text = 'PRINT';
            app.SAVEButton = uibutton(app.GRAPHICINFORMATIONPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.GRAPHICPanel = uipanel(app.GRAPHICINFORMATIONPanel);
            app.GRAPHICPanel.Title = 'GRAPHIC';
            app.SAATLabel = uilabel(app.GRAPHICPanel);
            app.SAATLabel.Text = 'DATE :';
            app.DATEEditField = uieditfield(app.GRAPHICPanel, 'text');
            app.DATEEditField.ValueChangingFcn = createCallbackFcn(app, @DATEEditFieldValueChanging, true);
            app.TARHEditField = uieditfield(app.GRAPHICPanel, 'text');
            app.RefNumberLabel = uilabel(app.GRAPHICPanel);
            app.RefNumberLabel.Text = 'Ref Number:';
            app.RefNumberEditField = uieditfield(app.GRAPHICPanel, 'text');
            app.ResponsibleLabel = uilabel(app.GRAPHICPanel);
            app.ResponsibleLabel.Text = 'Responsible:';
            app.ResponsibleEditField = uieditfield(app.GRAPHICPanel, 'text');
            app.TARHEditField_2 = uieditfield(app.GRAPHICPanel, 'text');
            app.ForceNewtonNPanel = uipanel(app.GRAPHICINFORMATIONPanel);
            app.ForceNewtonNPanel.Title = 'Force (Newton (N))';
            app.F3EditField_2Label = uilabel(app.ForceNewtonNPanel);
            app.F3EditField_2Label.Text = 'F3 :';
            app.F3EditField_2 = uieditfield(app.ForceNewtonNPanel, 'text');
            app.F4Label = uilabel(app.ForceNewtonNPanel);
            app.F4Label.Text = 'F4 :';
            app.F4EditField_2 = uieditfield(app.ForceNewtonNPanel, 'text');
            app.F2EditFieldLabel = uilabel(app.ForceNewtonNPanel);
            app.F2EditFieldLabel.Text = 'F2 :';
            app.F2EditField = uieditfield(app.ForceNewtonNPanel, 'text');
            app.F1EditFieldLabel = uilabel(app.ForceNewtonNPanel);
            app.F1EditFieldLabel.Text = 'F1 :';
            app.F1EditField = uieditfield(app.ForceNewtonNPanel, 'text');
            app.FRF3F1Label = uilabel(app.ForceNewtonNPanel);
            app.FRF3F1Label.Text = 'FR :';
            app.FREditField = uieditfield(app.ForceNewtonNPanel, 'text');
            app.Image3_2 = uiimage(app.GRAPHICINFORMATIONPanel);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'image_processing20220801-17461-1t9eqro.png');
            app.CLEARButton = uibutton(app.GRAPHICINFORMATIONPanel, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.serialportnameexCOM3Label = uilabel(app.UIFigure);
            app.serialportnameexCOM3Label.Text = 'serial port name : ';
            app.serialportnameEditField = uieditfield(app.UIFigure, 'text');
            app.serialportnameEditField.Value = 'COM7';
            app.EnteraSerialPortNameButton = uibutton(app.UIFigure, 'push');
            app.EnteraSerialPortNameButton.ButtonPushedFcn = createCallbackFcn(app, @EnteraSerialPortNameButtonPushed, true);
            app.EnteraSerialPortNameButton.Icon = fullfile(pathToMLAPP, '453178.png');
            app.EnteraSerialPortNameButton.Text = 'Connect(COM)';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Help_icon-icons.com_55891.png');
            app.readFromCOMButton = uibutton(app.UIFigure, 'push');
            app.readFromCOMButton.ButtonPushedFcn = createCallbackFcn(app, @readFromCOMButtonPushed, true);
            app.readFromCOMButton.Enable = 'off';
            app.readFromCOMButton.Visible = 'off';
            app.readFromCOMButton.Text = 'read From COM';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangingFcn = createCallbackFcn(app, @EditField_3ValueChanging, true);
            app.YollaButton = uibutton(app.UIFigure, 'push');
            app.YollaButton.ButtonPushedFcn = createCallbackFcn(app, @YollaButtonPushed, true);
            app.YollaButton.Enable = 'off';
            app.YollaButton.Visible = 'off';
            app.YollaButton.Text = 'Yolla';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.SstartTestButton = uibutton(app.UIFigure, 'push');
            app.SstartTestButton.ButtonPushedFcn = createCallbackFcn(app, @SstartTestButtonPushed2, true);
            app.SstartTestButton.Icon = fullfile(pathToMLAPP, 'start-icon-png-7.jpg');
            app.SstartTestButton.Enable = 'off';
            app.SstartTestButton.Text = 'Start Test';
            app.flagacButton = uibutton(app.UIFigure, 'push');
            app.flagacButton.ButtonPushedFcn = createCallbackFcn(app, @flagacButtonPushed, true);
            app.flagacButton.Visible = 'off';
            app.flagacButton.Text = 'flag ac';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Visible = 'off';
            app.startButton.Text = 'start';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Icon = fullfile(pathToMLAPP, 'emergencyff.png');
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.EditFieldTextArea = uitextarea(app.UIFigure);
            app.SearchListBox = uilistbox(app.UIFigure);
            app.SearchListBox.Visible = 'off';
            app.SearchListBox.DoubleClickedFcn = createCallbackFcn(app, @SearchListBoxDoubleClicked, true);
            app.xButton = uibutton(app.UIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Visible = 'off';
            app.xButton.Text = 'x';
            app.SearchListBoxLabel = uilabel(app.UIFigure);
            app.SearchListBoxLabel.Visible = 'off';
            app.SearchListBoxLabel.Text = 'Search:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end